classdef Frequenzanzeige < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        Button_selectFile    matlab.ui.control.Button
        DateiEditFieldLabel  matlab.ui.control.Label
        DateiEditField       matlab.ui.control.EditField
        UITable              matlab.ui.control.Table
        Button_down          matlab.ui.control.Button
        Button_up            matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [y] = analyzeFile(app,pathToFile)
            
        [fileID,errmsg] = fopen(pathToFile,'r');
        if fileID<0 % Here We Have a Problem
            app.DateiEditField.Value = errmsg;
            return;
        end   %% Here it is successful
        i = 1;
        
        while ~feof(fileID)
            line = fgets(fileID);
            
            if i == 1
                result_data = textscan(line, '%s %s | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f |','Delimiter',' | ');
            else
                result_data = [result_data;textscan(line, '%s %s | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f | %f |','Delimiter',' | ')];
            end
            i = i+1;
            
        end
        
        end
        function [y] =get(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_selectFile
        function Button_selectFilePushed(app, event)
            [selectedfile,selectedpath] = uigetfile('*.txt','MultiSelect','on');
            if isequal(selectedfile,0)
                disp('User selected Cancel');
            else
                if isstr(selectedfile), selectedfile={selectedfile}; end
                for  i= 1:size(selectedfile,2)
                NewRow = {fullfile(selectedpath,char(selectedfile(i))) 'n.V.' 'n.V.'};
                app.UITable.Data = [app.UITable.Data; NewRow];
                end
                
            end
            
         
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.DateiEditField.Value = char(app.UITable.Data(indices(1),1));
            analyzeFile(app, app.DateiEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frequenzverlauf')
            xlabel(app.UIAxes, 'Zeit')
            ylabel(app.UIAxes, 'Frequenz')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [47 53];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Button_selectFile = uibutton(app.UIFigure, 'push');
            app.Button_selectFile.ButtonPushedFcn = createCallbackFcn(app, @Button_selectFilePushed, true);
            app.Button_selectFile.Text = 'Datei auswählen';
            app.DateiEditFieldLabel = uilabel(app.UIFigure);
            app.DateiEditFieldLabel.Text = 'Datei:';
            app.DateiEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Datei'; 'Datum'; 'Durchschnitt'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button_down = uibutton(app.UIFigure, 'push');
            app.Button_down.Text = '↑';
            app.Button_up = uibutton(app.UIFigure, 'push');
            app.Button_up.Text = '↓';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frequenzanzeige

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end